classdef HB11TEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        InputGrid                       matlab.ui.container.GridLayout
        InputNavigationBar              matlab.ui.container.TabGroup
        InputLaserTab                   matlab.ui.container.Tab
        InputLaserGrid                  matlab.ui.container.GridLayout
        InputLaserPanel                 matlab.ui.container.Panel
        InputLaserSubGrid               matlab.ui.container.GridLayout
        LaserEfficiencyDecimalFormEditField  matlab.ui.control.NumericEditField
        LaserEfficiencyDecimalFormEditFieldLabel  matlab.ui.control.Label
        RepetitionRateHzEditField       matlab.ui.control.NumericEditField
        RepetitionRateHzEditFieldLabel  matlab.ui.control.Label
        TotalLaserEnergyPerPulseMJEditField  matlab.ui.control.NumericEditField
        TotalLaserEnergyPerPulseMJEditFieldLabel  matlab.ui.control.Label
        InputReactorTab                 matlab.ui.container.Tab
        InputReactorGrid                matlab.ui.container.GridLayout
        ReactorPanel                    matlab.ui.container.Panel
        InputReactorSubGrid             matlab.ui.container.GridLayout
        DutyCycleDecimalFormEditField   matlab.ui.control.NumericEditField
        DutyCycleDecimalFormEditFieldLabel  matlab.ui.control.Label
        IncreasedGainbyXFactorRatioEditField  matlab.ui.control.NumericEditField
        IncreasedGainbyXFactorRatioEditFieldLabel  matlab.ui.control.Label
        InstrumentationandControlSystemCostMillionsEditField  matlab.ui.control.NumericEditField
        InstrumentationandControlSystemCostMillionsEditFieldLabel  matlab.ui.control.Label
        ReactorShieldingInitialCostMillionsEditField  matlab.ui.control.NumericEditField
        ReactorShieldingInitialCostMillionsEditFieldLabel  matlab.ui.control.Label
        TargetGainRatioEditField        matlab.ui.control.NumericEditField
        TargetGainRatioEditFieldLabel   matlab.ui.control.Label
        InputPowerTab                   matlab.ui.container.Tab
        InputPowerGrid                  matlab.ui.container.GridLayout
        InputPowerPanel                 matlab.ui.container.Panel
        InputPowerSubGrid               matlab.ui.container.GridLayout
        ElectricityRateMWhEditField     matlab.ui.control.NumericEditField
        ElectricityRateMWhEditFieldLabel  matlab.ui.control.Label
        TurbinePlantEquipmentEditField  matlab.ui.control.NumericEditField
        TurbinePlantEquipmentLabel      matlab.ui.control.Label
        ElectricityGeneratorEfficiencyDecimalFormEditField  matlab.ui.control.NumericEditField
        ElectricityGeneratorEfficiencyDecimalFormEditFieldLabel  matlab.ui.control.Label
        InputFuelManTab                 matlab.ui.container.Tab
        InputFuelManGrid                matlab.ui.container.GridLayout
        InputFuelManPanel               matlab.ui.container.Panel
        InputFuelManSubGrid             matlab.ui.container.GridLayout
        FinalAssemblyCostperTargetYearEditField  matlab.ui.control.NumericEditField
        FinalAssemblyCostperTargetYearEditFieldLabel  matlab.ui.control.Label
        ManufacturingOverheadCostsYearEditField  matlab.ui.control.NumericEditField
        ManufacturingOverheadCostsYearEditFieldLabel  matlab.ui.control.Label
        TotalCostofManufacturingFacilitiesEditField  matlab.ui.control.NumericEditField
        TotalCostofManufacturingFacilitiesEditFieldLabel  matlab.ui.control.Label
        TotalCostofFuelDevelopmentEditField  matlab.ui.control.NumericEditField
        TotalCostofFuelDevelopmentEditFieldLabel  matlab.ui.control.Label
        CostofManufacturingperCapacitiveCoilandHydrogenConeEditField  matlab.ui.control.NumericEditField
        ElectricityGeneratorEfficiencyDecimalFormEditField_13Label  matlab.ui.control.Label
        TotalMaterialCostperCapacitiveCoilandHydrogenConeEditField  matlab.ui.control.NumericEditField
        TotalMaterialCostperCapacitiveCoilandHydrogenConeEditFieldLabel  matlab.ui.control.Label
        CapacitiveCoilandHydrogenConeBooleanEditField  matlab.ui.control.NumericEditField
        CapacitiveCoilandHydrogenConeBooleanEditFieldLabel  matlab.ui.control.Label
        TotalCostofXFactorEditField     matlab.ui.control.NumericEditField
        TotalCostofXFactorEditFieldLabel  matlab.ui.control.Label
        XFactorBooleanEditField         matlab.ui.control.NumericEditField
        XFactorBooleanEditFieldLabel    matlab.ui.control.Label
        CostofManufacturingPerPelletEditField  matlab.ui.control.NumericEditField
        CostofManufacturingPerPelletLabel  matlab.ui.control.Label
        CostofBoronNitrideKgEditField   matlab.ui.control.NumericEditField
        CostofBoronNitrideKgEditFieldLabel  matlab.ui.control.Label
        FuelPercentageBoronNitrideDecimalFormEditField  matlab.ui.control.NumericEditField
        FuelPercentageBoronNitrideDecimalFormEditFieldLabel  matlab.ui.control.Label
        CostofHydrogenKgEditField       matlab.ui.control.NumericEditField
        CostofHydrogenKgEditFieldLabel  matlab.ui.control.Label
        HydrogenPrecursorMassgEditField  matlab.ui.control.NumericEditField
        HydrogenPrecursorMassgEditFieldLabel  matlab.ui.control.Label
        FuelPelletMassgEditField        matlab.ui.control.NumericEditField
        FuelPelletMassgEditFieldLabel   matlab.ui.control.Label
        ProductionFailureRateDecimalFormEditField  matlab.ui.control.NumericEditField
        ProductionFailureRateDecimalFormEditFieldLabel  matlab.ui.control.Label
        FuelStoreTab                    matlab.ui.container.Tab
        InputFuelStoreGrid              matlab.ui.container.GridLayout
        InputFuelStorePanel             matlab.ui.container.Panel
        InputFuelStoreSubGrid           matlab.ui.container.GridLayout
        TotalCostofFuelStorageEditField  matlab.ui.control.NumericEditField
        TotalCostofFuelStorageEditFieldLabel  matlab.ui.control.Label
        CostofFuelDeliveryRobotEditField  matlab.ui.control.NumericEditField
        CostofFuelDeliveryRobotEditFieldLabel  matlab.ui.control.Label
        StackHeightTargetsEditField     matlab.ui.control.NumericEditField
        StackHeightTargetsEditFieldLabel  matlab.ui.control.Label
        RequiredRedundancyDaysEditField  matlab.ui.control.NumericEditField
        RequiredRedundancyDaysEditFieldLabel  matlab.ui.control.Label
        StorageFootprintperTargetm2EditField  matlab.ui.control.NumericEditField
        StorageFootprintperTargetm2EditFieldLabel  matlab.ui.control.Label
        StorageCostm2EditField          matlab.ui.control.NumericEditField
        StorageCostm2EditFieldLabel     matlab.ui.control.Label
        InputMaintenanceTab             matlab.ui.container.Tab
        InputMaintenanceGrid            matlab.ui.container.GridLayout
        InputMaintenancePanel           matlab.ui.container.Panel
        InputMaintenanceSubGrid         matlab.ui.container.GridLayout
        InputConstructionTab            matlab.ui.container.Tab
        InputConstructionGrid           matlab.ui.container.GridLayout
        InputConstructionPanel          matlab.ui.container.Panel
        InputConstructionSubGrid        matlab.ui.container.GridLayout
        InputCoolingTab                 matlab.ui.container.Tab
        InputCoolingGrid                matlab.ui.container.GridLayout
        InputCoolingPanel               matlab.ui.container.Panel
        InputCoolingSubGrid             matlab.ui.container.GridLayout
        InputPersonnelTab               matlab.ui.container.Tab
        InputPersonnelGrid              matlab.ui.container.GridLayout
        InputPersonnelPanel             matlab.ui.container.Panel
        InputPersonnelSubGrid           matlab.ui.container.GridLayout
        InputActions                    matlab.ui.container.GridLayout
        RunModelButton                  matlab.ui.control.Button
        OutputsTab                      matlab.ui.container.Tab
        OutputGrid                      matlab.ui.container.GridLayout
        OutputNavigationbar             matlab.ui.container.TabGroup
        OutputLaserTab                  matlab.ui.container.Tab
        OutputLaserGrid                 matlab.ui.container.GridLayout
        OutputLaserPanel                matlab.ui.container.Panel
        OutputLaserSubPanel             matlab.ui.container.GridLayout
        OutputReactorTab                matlab.ui.container.Tab
        OutputReactorGrid               matlab.ui.container.GridLayout
        OutputReactorPanel              matlab.ui.container.Panel
        OutputReactorSubGrid            matlab.ui.container.GridLayout
        OutputPowerTab                  matlab.ui.container.Tab
        OutputPowerGrid                 matlab.ui.container.GridLayout
        OutputPowerPanel                matlab.ui.container.Panel
        OutputReactorSubPanel           matlab.ui.container.GridLayout
        OutputFuelManTab                matlab.ui.container.Tab
        OutputFuelManGrid               matlab.ui.container.GridLayout
        OutputFuelManPanel              matlab.ui.container.Panel
        OutputFuelManSubGrid            matlab.ui.container.GridLayout
        OutputFuelStoreTab              matlab.ui.container.Tab
        OutputFuelStoreGrid             matlab.ui.container.GridLayout
        OutputFuelStorePanel            matlab.ui.container.Panel
        OutputFuelStoreSubGrid          matlab.ui.container.GridLayout
        OutputMaintenanceTab            matlab.ui.container.Tab
        OutputMaintenanceGrid           matlab.ui.container.GridLayout
        OutputMaintenancePanel          matlab.ui.container.Panel
        OutputMaintenanceSubGrid        matlab.ui.container.GridLayout
        OutputConstructionTab           matlab.ui.container.Tab
        OutputConstructionGrid          matlab.ui.container.GridLayout
        OutputConstructionPanel         matlab.ui.container.Panel
        OutputConstructionSubGrid       matlab.ui.container.GridLayout
        OutputCoolingTab                matlab.ui.container.Tab
        OutputCoolingGrid               matlab.ui.container.GridLayout
        OutputCoolingPanel              matlab.ui.container.Panel
        OutputCoolingSubGrid            matlab.ui.container.GridLayout
        OutputPersonnelTab              matlab.ui.container.Tab
        OutputPersonnelGrid             matlab.ui.container.GridLayout
        OutputPersonnelPanel            matlab.ui.container.Panel
        OutputPersonnelSubPanel         matlab.ui.container.GridLayout
        OptionsTab                      matlab.ui.container.Tab
        OptionsLayout                   matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        SavethissessionLabel            matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        OpenaprevioussessionLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppLayer = ApplicationModule() % Instance of the Application Layer
    end
    
    methods (Access = private)
        
        % p_MapApplicationLayerToInputs : Sets inputs to data in store
        function p_MapApplicationLayerToInputs(app)

            % --- Extract Input Values from Application Layer ---
            inputs = app.AppLayer.PostInputs();

            % --- Load Into Input Fields ---
            children = app.InputPanelLayout.Children;

            % Check that number of children is even
            num_children = length(children);

            if mod(num_children, 2) ~= 0
                error("Failed to parse inputs.");
            end
            
            % Note : This loop depends on assumptions 1 and 2 detailed above
            for i = 1:2:(num_children - 1)
                
                % Process Key (Remove Spaces, Set Lowercase append input specifier)
                key = children(i).Text;
                key = strrep(key, " ", "");
                key = lower(key);
                key = strcat("in_", key);
                
                % Set Value
                children(i+1).Value = inputs.(key);

            end
            
        end
        
        function p_PlotElectricityGenerated(app, outputs)
            elec_out_data = outputs.("out_electricitygenerated");
            periods = elec_out_data.("period");
            outputs = elec_out_data.("output");
            plot(app.ElectricityGenerationAxes, periods, outputs);

        end
        
        function p_MapApplicationLayerToOutputs(app)
            
            % --- Extract Outputs from Application Layer ---
            outputs = app.AppLayer.PostOutputs();

            % --- Load Into UI Outputs ---
            app.p_PlotElectricityGenerated(outputs);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function OutputNavTreeSelectionChanged(app, event)
            
            % Array of selected TreeNodes
            selectedNodes = app.OutputNavTree.SelectedNodes;

            % --- Check if the node is a parent (Has Children) ---
            if ~isempty(selectedNodes.Children)
                return;
            end
            
            % --- Change the Title ---
            selectedOutputName= selectedNodes.Text;
            app.OutputPanel.Title = selectedOutputName;

            % --- Change the Rendered Content Panel ---

            % Clear all selected panels
            set(app.OutputPanel.Children, 'Visible', 'off');
            
            % Conditionally show a child panel
            switch selectedOutputName
           
            end


        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Get File Path
            [filename, path] = uigetfile('*.xml');
            
            % Handle the user cancelling
            if filename == 0
                return;
            end
            
            % Load File into Memory
            app.AppLayer.OpenState(strcat(path, filename));
            app.p_MapApplicationLayerToInputs();
            app.p_MapApplicationLayerToOutputs();

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Select Output Destination
            [filename, path] = uiputfile('*.xml');

            % Handle User Cancel
            if filename == 0
                return;
            end

            % Save to specified file
            app.AppLayer.SaveState(strcat(path, filename));

        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            
            % --- Load Inputs into the application layer ---

            % Assumption 1 : Children = [ lbl1, val1, lbl2, val2, ... ]
            % Assumption 2 : Even number of children

            input_data = struct();

            children = app.InputPanelLayout.Children;

            % Check that number of children is even
            num_children = length(children);

            if mod(num_children, 2) ~= 0
                error("Failed to parse inputs.");
            end
            
            % Note : This loop depends on assumptions 1 and 2 detailed above
            for i = 1:2:(num_children - 1)
                
                % Process Key (Remove Spaces, Set Lowercase append input specifier)
                key = children(i).Text;
                key = strrep(key, " ", "");
                key = lower(key);
                key = strcat("in_", key);
                
                % Process Value
                value = children(i+1).Value;
                
                % Insert into Struct
                input_data.(key) = value;

            end
           
            % Load into the application layer
            app.AppLayer.LoadInputs(input_data);

            % --- Run the Model ---
            app.AppLayer.RunModel();
         
            % --- Update Outputs in UI ---
            app.p_MapApplicationLayerToOutputs();
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputGrid = uigridlayout(app.InputsTab);
            app.InputActions = uigridlayout(app.InputGrid);
            app.RunModelButton = uibutton(app.InputActions, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.InputNavigationBar = uitabgroup(app.InputGrid);
            app.InputLaserTab = uitab(app.InputNavigationBar);
            app.InputLaserTab.Title = 'Laser';
            app.InputLaserGrid = uigridlayout(app.InputLaserTab);
            app.InputLaserPanel = uipanel(app.InputLaserGrid);
            app.InputLaserPanel.Title = 'Primary Laser';
            app.InputLaserSubGrid = uigridlayout(app.InputLaserPanel);
            app.TotalLaserEnergyPerPulseMJEditFieldLabel = uilabel(app.InputLaserSubGrid);
            app.TotalLaserEnergyPerPulseMJEditFieldLabel.Text = 'Total Laser Energy Per Pulse (MJ)';
            app.TotalLaserEnergyPerPulseMJEditField = uieditfield(app.InputLaserSubGrid, 'numeric');
            app.RepetitionRateHzEditFieldLabel = uilabel(app.InputLaserSubGrid);
            app.RepetitionRateHzEditFieldLabel.Text = 'Repetition Rate (Hz)';
            app.RepetitionRateHzEditField = uieditfield(app.InputLaserSubGrid, 'numeric');
            app.LaserEfficiencyDecimalFormEditFieldLabel = uilabel(app.InputLaserSubGrid);
            app.LaserEfficiencyDecimalFormEditFieldLabel.Text = 'Laser Efficiency (% Decimal Form)';
            app.LaserEfficiencyDecimalFormEditField = uieditfield(app.InputLaserSubGrid, 'numeric');
            app.InputReactorTab = uitab(app.InputNavigationBar);
            app.InputReactorTab.Title = 'Reactor';
            app.InputReactorGrid = uigridlayout(app.InputReactorTab);
            app.ReactorPanel = uipanel(app.InputReactorGrid);
            app.ReactorPanel.Title = 'Reactor';
            app.InputReactorSubGrid = uigridlayout(app.ReactorPanel);
            app.TargetGainRatioEditFieldLabel = uilabel(app.InputReactorSubGrid);
            app.TargetGainRatioEditFieldLabel.Text = 'Target Gain (Ratio)';
            app.TargetGainRatioEditField = uieditfield(app.InputReactorSubGrid, 'numeric');
            app.ReactorShieldingInitialCostMillionsEditFieldLabel = uilabel(app.InputReactorSubGrid);
            app.ReactorShieldingInitialCostMillionsEditFieldLabel.Text = 'Reactor Shielding Initial Cost ($ Millions)';
            app.ReactorShieldingInitialCostMillionsEditField = uieditfield(app.InputReactorSubGrid, 'numeric');
            app.InstrumentationandControlSystemCostMillionsEditFieldLabel = uilabel(app.InputReactorSubGrid);
            app.InstrumentationandControlSystemCostMillionsEditFieldLabel.Text = 'Instrumentation and Control System Cost ($ Millions)';
            app.InstrumentationandControlSystemCostMillionsEditField = uieditfield(app.InputReactorSubGrid, 'numeric');
            app.IncreasedGainbyXFactorRatioEditFieldLabel = uilabel(app.InputReactorSubGrid);
            app.IncreasedGainbyXFactorRatioEditFieldLabel.Text = 'Increased Gain by X Factor (Ratio)';
            app.IncreasedGainbyXFactorRatioEditField = uieditfield(app.InputReactorSubGrid, 'numeric');
            app.DutyCycleDecimalFormEditFieldLabel = uilabel(app.InputReactorSubGrid);
            app.DutyCycleDecimalFormEditFieldLabel.Text = 'Duty Cycle (% Decimal Form)';
            app.DutyCycleDecimalFormEditField = uieditfield(app.InputReactorSubGrid, 'numeric');
            app.InputPowerTab = uitab(app.InputNavigationBar);
            app.InputPowerTab.Title = 'Power';
            app.InputPowerGrid = uigridlayout(app.InputPowerTab);
            app.InputPowerPanel = uipanel(app.InputPowerGrid);
            app.InputPowerPanel.Title = 'Power Generation';
            app.InputPowerSubGrid = uigridlayout(app.InputPowerPanel);
            app.ElectricityGeneratorEfficiencyDecimalFormEditFieldLabel = uilabel(app.InputPowerSubGrid);
            app.ElectricityGeneratorEfficiencyDecimalFormEditFieldLabel.Text = 'Electricity Generator Efficiency (% Decimal Form)';
            app.ElectricityGeneratorEfficiencyDecimalFormEditField = uieditfield(app.InputPowerSubGrid, 'numeric');
            app.TurbinePlantEquipmentLabel = uilabel(app.InputPowerSubGrid);
            app.TurbinePlantEquipmentLabel.Text = 'Turbine Plant Equipment ($)';
            app.TurbinePlantEquipmentEditField = uieditfield(app.InputPowerSubGrid, 'numeric');
            app.ElectricityRateMWhEditFieldLabel = uilabel(app.InputPowerSubGrid);
            app.ElectricityRateMWhEditFieldLabel.Text = 'Electricity Rate ($/MWh)';
            app.ElectricityRateMWhEditField = uieditfield(app.InputPowerSubGrid, 'numeric');
            app.InputFuelManTab = uitab(app.InputNavigationBar);
            app.InputFuelManTab.Title = 'Fuel Man';
            app.InputFuelManGrid = uigridlayout(app.InputFuelManTab);
            app.InputFuelManPanel = uipanel(app.InputFuelManGrid);
            app.InputFuelManPanel.Title = 'Fuel Manufacturing';
            app.InputFuelManSubGrid = uigridlayout(app.InputFuelManPanel);
            app.ProductionFailureRateDecimalFormEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.ProductionFailureRateDecimalFormEditFieldLabel.Text = 'Production Failure Rate (% Decimal Form)';
            app.ProductionFailureRateDecimalFormEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.FuelPelletMassgEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.FuelPelletMassgEditFieldLabel.Text = 'Fuel Pellet Mass (g)';
            app.FuelPelletMassgEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.HydrogenPrecursorMassgEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.HydrogenPrecursorMassgEditFieldLabel.Text = 'Hydrogen Precursor Mass (g)';
            app.HydrogenPrecursorMassgEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.CostofHydrogenKgEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.CostofHydrogenKgEditFieldLabel.Text = 'Cost of Hydrogen ($/Kg)';
            app.CostofHydrogenKgEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.FuelPercentageBoronNitrideDecimalFormEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.FuelPercentageBoronNitrideDecimalFormEditFieldLabel.Text = 'Fuel Percentage Boron Nitride (% Decimal Form)';
            app.FuelPercentageBoronNitrideDecimalFormEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.CostofBoronNitrideKgEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.CostofBoronNitrideKgEditFieldLabel.Text = 'Cost of Boron Nitride ($/Kg)';
            app.CostofBoronNitrideKgEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.CostofManufacturingPerPelletLabel = uilabel(app.InputFuelManSubGrid);
            app.CostofManufacturingPerPelletLabel.Text = 'Cost of Manufacturing Per Pellet ($)';
            app.CostofManufacturingPerPelletEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.XFactorBooleanEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.XFactorBooleanEditFieldLabel.Text = 'X Factor (Boolean)';
            app.XFactorBooleanEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.TotalCostofXFactorEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.TotalCostofXFactorEditFieldLabel.Text = 'Total Cost of X Factor ($)';
            app.TotalCostofXFactorEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.CapacitiveCoilandHydrogenConeBooleanEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.CapacitiveCoilandHydrogenConeBooleanEditFieldLabel.Text = 'Capacitive Coil and Hydrogen Cone (Boolean)';
            app.CapacitiveCoilandHydrogenConeBooleanEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.TotalMaterialCostperCapacitiveCoilandHydrogenConeEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.TotalMaterialCostperCapacitiveCoilandHydrogenConeEditFieldLabel.Text = 'Total Material Cost per Capacitive Coil and Hydrogen Cone ($)';
            app.TotalMaterialCostperCapacitiveCoilandHydrogenConeEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_13Label = uilabel(app.InputFuelManSubGrid);
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_13Label.Text = 'Cost of Manufacturing per Capacitive Coil and Hydrogen Cone ($)';
            app.CostofManufacturingperCapacitiveCoilandHydrogenConeEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.TotalCostofFuelDevelopmentEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.TotalCostofFuelDevelopmentEditFieldLabel.Text = 'Total Cost of Fuel Development ($)';
            app.TotalCostofFuelDevelopmentEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.TotalCostofManufacturingFacilitiesEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.TotalCostofManufacturingFacilitiesEditFieldLabel.Text = 'Total Cost of Manufacturing Facilities ($)';
            app.TotalCostofManufacturingFacilitiesEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.ManufacturingOverheadCostsYearEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.ManufacturingOverheadCostsYearEditFieldLabel.Text = 'Manufacturing Overhead Costs ($/Year)';
            app.ManufacturingOverheadCostsYearEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.FinalAssemblyCostperTargetYearEditFieldLabel = uilabel(app.InputFuelManSubGrid);
            app.FinalAssemblyCostperTargetYearEditFieldLabel.Text = 'Final Assembly Cost per Target ($/Year)';
            app.FinalAssemblyCostperTargetYearEditField = uieditfield(app.InputFuelManSubGrid, 'numeric');
            app.FuelStoreTab = uitab(app.InputNavigationBar);
            app.FuelStoreTab.Title = 'Fuel Store';
            app.InputFuelStoreGrid = uigridlayout(app.FuelStoreTab);
            app.InputFuelStorePanel = uipanel(app.InputFuelStoreGrid);
            app.InputFuelStorePanel.Title = 'Fuel Storage / Delivery';
            app.InputFuelStoreSubGrid = uigridlayout(app.InputFuelStorePanel);
            app.StorageCostm2EditFieldLabel = uilabel(app.InputFuelStoreSubGrid);
            app.StorageCostm2EditFieldLabel.Text = 'Storage Cost ($/m^2)';
            app.StorageCostm2EditField = uieditfield(app.InputFuelStoreSubGrid, 'numeric');
            app.StorageFootprintperTargetm2EditFieldLabel = uilabel(app.InputFuelStoreSubGrid);
            app.StorageFootprintperTargetm2EditFieldLabel.Text = 'Storage Footprint per Target (m^2)';
            app.StorageFootprintperTargetm2EditField = uieditfield(app.InputFuelStoreSubGrid, 'numeric');
            app.RequiredRedundancyDaysEditFieldLabel = uilabel(app.InputFuelStoreSubGrid);
            app.RequiredRedundancyDaysEditFieldLabel.Text = 'Required Redundancy (Days)';
            app.RequiredRedundancyDaysEditField = uieditfield(app.InputFuelStoreSubGrid, 'numeric');
            app.StackHeightTargetsEditFieldLabel = uilabel(app.InputFuelStoreSubGrid);
            app.StackHeightTargetsEditFieldLabel.Text = 'Stack Height (Targets)';
            app.StackHeightTargetsEditField = uieditfield(app.InputFuelStoreSubGrid, 'numeric');
            app.CostofFuelDeliveryRobotEditFieldLabel = uilabel(app.InputFuelStoreSubGrid);
            app.CostofFuelDeliveryRobotEditFieldLabel.Text = 'Cost of Fuel Delivery Robot ($)';
            app.CostofFuelDeliveryRobotEditField = uieditfield(app.InputFuelStoreSubGrid, 'numeric');
            app.TotalCostofFuelStorageEditFieldLabel = uilabel(app.InputFuelStoreSubGrid);
            app.TotalCostofFuelStorageEditFieldLabel.Text = 'Total Cost of Fuel Storage ($)';
            app.TotalCostofFuelStorageEditField = uieditfield(app.InputFuelStoreSubGrid, 'numeric');
            app.InputMaintenanceTab = uitab(app.InputNavigationBar);
            app.InputMaintenanceTab.Title = 'Maintenance';
            app.InputMaintenanceGrid = uigridlayout(app.InputMaintenanceTab);
            app.InputMaintenancePanel = uipanel(app.InputMaintenanceGrid);
            app.InputMaintenancePanel.Title = 'Maintenance';
            app.InputMaintenanceSubGrid = uigridlayout(app.InputMaintenancePanel);
            app.InputConstructionTab = uitab(app.InputNavigationBar);
            app.InputConstructionTab.Title = 'Construction';
            app.InputConstructionGrid = uigridlayout(app.InputConstructionTab);
            app.InputConstructionPanel = uipanel(app.InputConstructionGrid);
            app.InputConstructionPanel.Title = 'Construction';
            app.InputConstructionSubGrid = uigridlayout(app.InputConstructionPanel);
            app.InputCoolingTab = uitab(app.InputNavigationBar);
            app.InputCoolingTab.Title = 'Cooling';
            app.InputCoolingGrid = uigridlayout(app.InputCoolingTab);
            app.InputCoolingPanel = uipanel(app.InputCoolingGrid);
            app.InputCoolingPanel.Title = 'Cooling System';
            app.InputCoolingSubGrid = uigridlayout(app.InputCoolingPanel);
            app.InputPersonnelTab = uitab(app.InputNavigationBar);
            app.InputPersonnelTab.Title = 'Personnel';
            app.InputPersonnelGrid = uigridlayout(app.InputPersonnelTab);
            app.InputPersonnelPanel = uipanel(app.InputPersonnelGrid);
            app.InputPersonnelPanel.Title = 'Personnel';
            app.InputPersonnelSubGrid = uigridlayout(app.InputPersonnelPanel);
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputGrid = uigridlayout(app.OutputsTab);
            app.OutputNavigationbar = uitabgroup(app.OutputGrid);
            app.OutputLaserTab = uitab(app.OutputNavigationbar);
            app.OutputLaserTab.Title = 'Laser';
            app.OutputLaserGrid = uigridlayout(app.OutputLaserTab);
            app.OutputLaserPanel = uipanel(app.OutputLaserGrid);
            app.OutputLaserPanel.Title = 'Primary Laser';
            app.OutputLaserSubPanel = uigridlayout(app.OutputLaserPanel);
            app.OutputReactorTab = uitab(app.OutputNavigationbar);
            app.OutputReactorTab.Title = 'Reactor';
            app.OutputReactorGrid = uigridlayout(app.OutputReactorTab);
            app.OutputReactorPanel = uipanel(app.OutputReactorGrid);
            app.OutputReactorPanel.Title = 'Reactor';
            app.OutputReactorSubGrid = uigridlayout(app.OutputReactorPanel);
            app.OutputPowerTab = uitab(app.OutputNavigationbar);
            app.OutputPowerTab.Title = 'Power';
            app.OutputPowerGrid = uigridlayout(app.OutputPowerTab);
            app.OutputPowerPanel = uipanel(app.OutputPowerGrid);
            app.OutputPowerPanel.Title = 'Power Generation';
            app.OutputReactorSubPanel = uigridlayout(app.OutputPowerPanel);
            app.OutputFuelManTab = uitab(app.OutputNavigationbar);
            app.OutputFuelManTab.Title = 'Fuel Man';
            app.OutputFuelManGrid = uigridlayout(app.OutputFuelManTab);
            app.OutputFuelManPanel = uipanel(app.OutputFuelManGrid);
            app.OutputFuelManPanel.Title = 'Fuel Manufacturing, Storage and Delivery';
            app.OutputFuelManSubGrid = uigridlayout(app.OutputFuelManPanel);
            app.OutputFuelStoreTab = uitab(app.OutputNavigationbar);
            app.OutputFuelStoreTab.Title = 'Fuel Store';
            app.OutputFuelStoreGrid = uigridlayout(app.OutputFuelStoreTab);
            app.OutputFuelStorePanel = uipanel(app.OutputFuelStoreGrid);
            app.OutputFuelStorePanel.Title = 'Fuel Manufacturing, Storage and Delivery';
            app.OutputFuelStoreSubGrid = uigridlayout(app.OutputFuelStorePanel);
            app.OutputMaintenanceTab = uitab(app.OutputNavigationbar);
            app.OutputMaintenanceTab.Title = 'Maintenance';
            app.OutputMaintenanceGrid = uigridlayout(app.OutputMaintenanceTab);
            app.OutputMaintenancePanel = uipanel(app.OutputMaintenanceGrid);
            app.OutputMaintenancePanel.Title = 'Maintenance';
            app.OutputMaintenanceSubGrid = uigridlayout(app.OutputMaintenancePanel);
            app.OutputConstructionTab = uitab(app.OutputNavigationbar);
            app.OutputConstructionTab.Title = 'Construction';
            app.OutputConstructionGrid = uigridlayout(app.OutputConstructionTab);
            app.OutputConstructionPanel = uipanel(app.OutputConstructionGrid);
            app.OutputConstructionPanel.Title = 'Construction';
            app.OutputConstructionSubGrid = uigridlayout(app.OutputConstructionPanel);
            app.OutputCoolingTab = uitab(app.OutputNavigationbar);
            app.OutputCoolingTab.Title = 'Cooling';
            app.OutputCoolingGrid = uigridlayout(app.OutputCoolingTab);
            app.OutputCoolingPanel = uipanel(app.OutputCoolingGrid);
            app.OutputCoolingPanel.Title = 'Cooling System';
            app.OutputCoolingSubGrid = uigridlayout(app.OutputCoolingPanel);
            app.OutputPersonnelTab = uitab(app.OutputNavigationbar);
            app.OutputPersonnelTab.Title = 'Personnel';
            app.OutputPersonnelGrid = uigridlayout(app.OutputPersonnelTab);
            app.OutputPersonnelPanel = uipanel(app.OutputPersonnelGrid);
            app.OutputPersonnelPanel.Title = 'Personnel';
            app.OutputPersonnelSubPanel = uigridlayout(app.OutputPersonnelPanel);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.OptionsLayout = uigridlayout(app.OptionsTab);
            app.OpenaprevioussessionLabel = uilabel(app.OptionsLayout);
            app.OpenaprevioussessionLabel.Text = 'Open a previous session';
            app.OpenButton = uibutton(app.OptionsLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SavethissessionLabel = uilabel(app.OptionsLayout);
            app.SavethissessionLabel.Text = 'Save this session';
            app.SaveButton = uibutton(app.OptionsLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HB11TEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end